classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        HistogramTab                   matlab.ui.container.Tab
        PreviewCitraMasukanLabel       matlab.ui.control.Label
        PathInfoLabel                  matlab.ui.control.Label
        PathcitraLabel                 matlab.ui.control.Label
        TampilkanHistogramButton       matlab.ui.control.Button
        PilihFileCitraButton           matlab.ui.control.Button
        CitramasukanLabel              matlab.ui.control.Label
        ImageAxes                      matlab.ui.control.UIAxes
        PerbaikanKualitasTab           matlab.ui.container.Tab
        PereganganKontrasPanel         matlab.ui.container.Panel
        ProsesPereganganKontrasButton  matlab.ui.control.Button
        TransformasiPangkatPanel       matlab.ui.container.Panel
        ProsesTransformasiPangkatButton  matlab.ui.control.Button
        ParameterLabel_3               matlab.ui.control.Label
        ySpinner                       matlab.ui.control.Spinner
        Label                          matlab.ui.control.Label
        cSpinner_2                     matlab.ui.control.Spinner
        cSpinner_2Label                matlab.ui.control.Label
        TransformasiLogPanel           matlab.ui.container.Panel
        ProsesTransformasiLogButton    matlab.ui.control.Button
        ParameterLabel_2               matlab.ui.control.Label
        rSpinner                       matlab.ui.control.Spinner
        rSpinnerLabel                  matlab.ui.control.Label
        cSpinner                       matlab.ui.control.Spinner
        cSpinnerLabel                  matlab.ui.control.Label
        PembalikanCitraPanel           matlab.ui.container.Panel
        ProsesBalikanCitraButton       matlab.ui.control.Button
        PencerahanCitraPanel           matlab.ui.container.Panel
        ProsesPencerahanCitraButton    matlab.ui.control.Button
        ParameterLabel                 matlab.ui.control.Label
        bSpinner                       matlab.ui.control.Spinner
        bSpinnerLabel                  matlab.ui.control.Label
        aSpinner                       matlab.ui.control.Spinner
        aSpinnerLabel                  matlab.ui.control.Label
        PreviewCitraMasukanLabel_2     matlab.ui.control.Label
        PathInfoLabel_2                matlab.ui.control.Label
        PathcitraLabel_2               matlab.ui.control.Label
        PilihFileCitraButton_2         matlab.ui.control.Button
        CitramasukanLabel_2            matlab.ui.control.Label
        ImageAxes_2                    matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function imagehistogram(~,img,name)
            % Menghitung histogram
            if size(img, 3) == 1
                % Citra Grayscale
                histValues = zeros(1, 256);
                for i = 1:size(img, 1)
                    for j = 1:size(img, 2)
                        intensity = img(i, j) + 1;
                        histValues(intensity) = histValues(intensity) + 1;
                    end
                end

                % Menampilkan histogram Grayscale dalam jendela popup
                figure('Name', ['Histogram Citra Grayscale dari',' ', name], 'NumberTitle', 'off');
                bar(histValues);
                xlabel('Derajat Keabuan');
                ylabel('Frekuensi');

            else
               % Citra Berwarna
                histValuesR = zeros(1, 256);
                histValuesG = zeros(1, 256);
                histValuesB = zeros(1, 256);
                for i = 1:size(img, 1)
                    for j = 1:size(img, 2)
                        intensityR = img(i, j, 1) + 1;
                        intensityG = img(i, j, 2) + 1;
                        intensityB = img(i, j, 3) + 1;
                        histValuesR(intensityR) = histValuesR(intensityR) + 1;
                        histValuesG(intensityG) = histValuesG(intensityG) + 1;
                        histValuesB(intensityB) = histValuesB(intensityB) + 1;
                    end
                end
                % Menampilkan histogram Citra Berwarna
                figure('Name', ['Histogram Citra Berwarna dari',' ', name], 'NumberTitle', 'off');
                subplot(3, 1, 1);
                bar(histValuesR, 'r');
                title('Histogram Red');
                xlabel('Derajat Keabuan');
                ylabel('Frekuensi');
                subplot(3, 1, 2);
                bar(histValuesG, 'g');
                title('Histogram Green');
                xlabel('Derajat Keabuan');
                ylabel('Frekuensi');
                subplot(3, 1, 3);
                bar(histValuesB, 'b');
                title('Histogram Blue');
                xlabel('Derajat Keabuan');
                ylabel('Frekuensi');
            end
        end


        function results = imagebrightening(~,img,a,b)
            results = a*img+b;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PilihFileCitraButton
        function PilihFileCitraButtonPushed(app, event)
            % Memilih gambar
            [fileName, pathName] = uigetfile({'*.png; *.jpg; *.jpeg; *.bmp', 'All Image Files'});
            fullPath = fullfile(pathName, fileName);

            if fileName ~= 0
                % Menampilkan path file di label
                app.PathInfoLabel.Text = fullPath;

                % Menampilkan citra di axes
                imshow(fullPath, 'Parent', app.ImageAxes);

                % Mengaktifkan tombol Tampilkan Histogram jika gambar dipilih
                app.TampilkanHistogramButton.Enable = 'on';
            end
        end

        % Button pushed function: TampilkanHistogramButton
        function TampilkanHistogramButtonPushed(app, event)
            % Memeriksa apakah ada gambar yang dipilih
            if isempty(app.PathInfoLabel.Text)
                msgbox('Pilih terlebih dahulu gambar sebelum menampilkan histogram.', 'Peringatan', 'warn');
                return; % Keluar dari callback jika tidak ada gambar yang dipilih
            end
            
            % Mendapatkan citra dari path
            img = imread(app.PathInfoLabel.Text);
            app.imagehistogram(img, 'Citra Masukan');

            
        end

        % Button pushed function: PilihFileCitraButton_2
        function PilihFileCitraButton_2Pushed(app, event)
            % Memilih gambar
            [fileName, pathName] = uigetfile({'*.png; *.jpg; *.jpeg; *.bmp', 'All Image Files'});
            fullPath = fullfile(pathName, fileName);

            if fileName ~= 0
                % Menampilkan path file di label
                app.PathInfoLabel_2.Text = fullPath;

                % Menampilkan citra di axes
                imshow(fullPath, 'Parent', app.ImageAxes_2);

                % Mengaktifkan tombol jika gambar dipilih
                app.ProsesPencerahanCitraButton.Enable = 'on';
                app.ProsesBalikanCitraButton.Enable = 'on';
                app.ProsesTransformasiLogButton.Enable = 'on';
                app.ProsesTransformasiPangkatButton.Enable = 'on';
                app.ProsesPereganganKontrasButton.Enable = 'on';
                app.aSpinner.Enable = 'on';
                app.bSpinner.Enable = 'on';
                app.cSpinner.Enable = 'on';
                app.rSpinner.Enable = 'on';
                app.cSpinner_2.Enable = 'on';
                app.ySpinner.Enable = 'on';

            end
        end

        % Button pushed function: ProsesPencerahanCitraButton
        function ProsesPencerahanCitraButtonPushed(app, event)
            % Mendapatkan citra masukan dari path
            img = imread(app.PathInfoLabel_2.Text);
            
            % Citra masukan
            figure("Name", "Citra Masukan",'NumberTitle', 'off')
            imshow(img)
            app.imagehistogram(img, 'Citra Masukan')

            % Citra luaran
            a = app.aSpinner.Value;
            b = app.bSpinner.Value;
            result = app.imagebrightening(img, a, b);
            figure("Name", "Citra Luaran",'NumberTitle', 'off')
            imshow(result)
            app.imagehistogram(result, 'Citra Hasil')

        end

        % Button pushed function: ProsesBalikanCitraButton
        function ProsesBalikanCitraButtonPushed(app, event)
            % Mendapatkan citra masukan dari path
            img = imread(app.PathInfoLabel_2.Text);
            
            % Citra masukan
            figure("Name", "Citra Masukan",'NumberTitle', 'off')
            imshow(img)
            app.imagehistogram(img, 'Citra Masukan')

            % Citra luaran
            result = 255 - img;
            figure("Name", "Citra Luaran",'NumberTitle', 'off')
            imshow(result)
            app.imagehistogram(result, 'Citra Hasil')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HistogramTab = uitab(app.TabGroup);
            app.HistogramTab.Title = 'Histogram';
            app.ImageAxes = uiaxes(app.HistogramTab);
            app.ImageAxes.XTick = [];
            app.ImageAxes.YTick = [];
            app.CitramasukanLabel = uilabel(app.HistogramTab);
            app.CitramasukanLabel.Text = 'Citra masukan:';
            app.PilihFileCitraButton = uibutton(app.HistogramTab, 'push');
            app.PilihFileCitraButton.ButtonPushedFcn = createCallbackFcn(app, @PilihFileCitraButtonPushed, true);
            app.PilihFileCitraButton.Text = 'Pilih File Citra';
            app.TampilkanHistogramButton = uibutton(app.HistogramTab, 'push');
            app.TampilkanHistogramButton.ButtonPushedFcn = createCallbackFcn(app, @TampilkanHistogramButtonPushed, true);
            app.TampilkanHistogramButton.Enable = 'off';
            app.TampilkanHistogramButton.Text = 'Tampilkan Histogram';
            app.PathcitraLabel = uilabel(app.HistogramTab);
            app.PathcitraLabel.Text = 'Path citra:';
            app.PathInfoLabel = uilabel(app.HistogramTab);
            app.PathInfoLabel.Text = '';
            app.PreviewCitraMasukanLabel = uilabel(app.HistogramTab);
            app.PreviewCitraMasukanLabel.Text = 'Preview Citra Masukan';
            app.PerbaikanKualitasTab = uitab(app.TabGroup);
            app.PerbaikanKualitasTab.Title = 'Perbaikan Kualitas';
            app.ImageAxes_2 = uiaxes(app.PerbaikanKualitasTab);
            app.ImageAxes_2.XTick = [];
            app.ImageAxes_2.YTick = [];
            app.CitramasukanLabel_2 = uilabel(app.PerbaikanKualitasTab);
            app.CitramasukanLabel_2.Text = 'Citra masukan:';
            app.PilihFileCitraButton_2 = uibutton(app.PerbaikanKualitasTab, 'push');
            app.PilihFileCitraButton_2.ButtonPushedFcn = createCallbackFcn(app, @PilihFileCitraButton_2Pushed, true);
            app.PilihFileCitraButton_2.Text = 'Pilih File Citra';
            app.PathcitraLabel_2 = uilabel(app.PerbaikanKualitasTab);
            app.PathcitraLabel_2.Text = 'Path citra:';
            app.PathInfoLabel_2 = uilabel(app.PerbaikanKualitasTab);
            app.PathInfoLabel_2.Text = '';
            app.PreviewCitraMasukanLabel_2 = uilabel(app.PerbaikanKualitasTab);
            app.PreviewCitraMasukanLabel_2.Text = 'Preview Citra Masukan';
            app.PencerahanCitraPanel = uipanel(app.PerbaikanKualitasTab);
            app.PencerahanCitraPanel.Title = 'Pencerahan Citra';
            app.aSpinnerLabel = uilabel(app.PencerahanCitraPanel);
            app.aSpinnerLabel.Enable = 'off';
            app.aSpinnerLabel.Text = 'a';
            app.aSpinner = uispinner(app.PencerahanCitraPanel);
            app.aSpinner.Enable = 'off';
            app.bSpinnerLabel = uilabel(app.PencerahanCitraPanel);
            app.bSpinnerLabel.Enable = 'off';
            app.bSpinnerLabel.Text = 'b';
            app.bSpinner = uispinner(app.PencerahanCitraPanel);
            app.bSpinner.Enable = 'off';
            app.ParameterLabel = uilabel(app.PencerahanCitraPanel);
            app.ParameterLabel.Text = 'Parameter';
            app.ProsesPencerahanCitraButton = uibutton(app.PencerahanCitraPanel, 'push');
            app.ProsesPencerahanCitraButton.ButtonPushedFcn = createCallbackFcn(app, @ProsesPencerahanCitraButtonPushed, true);
            app.ProsesPencerahanCitraButton.Enable = 'off';
            app.ProsesPencerahanCitraButton.Text = 'Proses Pencerahan Citra';
            app.PembalikanCitraPanel = uipanel(app.PerbaikanKualitasTab);
            app.PembalikanCitraPanel.Title = 'Pembalikan Citra';
            app.ProsesBalikanCitraButton = uibutton(app.PembalikanCitraPanel, 'push');
            app.ProsesBalikanCitraButton.ButtonPushedFcn = createCallbackFcn(app, @ProsesBalikanCitraButtonPushed, true);
            app.ProsesBalikanCitraButton.Enable = 'off';
            app.ProsesBalikanCitraButton.Text = 'Proses Balikan Citra';
            app.TransformasiLogPanel = uipanel(app.PerbaikanKualitasTab);
            app.TransformasiLogPanel.Title = 'Transformasi Log';
            app.cSpinnerLabel = uilabel(app.TransformasiLogPanel);
            app.cSpinnerLabel.Enable = 'off';
            app.cSpinnerLabel.Text = 'c';
            app.cSpinner = uispinner(app.TransformasiLogPanel);
            app.cSpinner.Enable = 'off';
            app.rSpinnerLabel = uilabel(app.TransformasiLogPanel);
            app.rSpinnerLabel.Enable = 'off';
            app.rSpinnerLabel.Text = 'r';
            app.rSpinner = uispinner(app.TransformasiLogPanel);
            app.rSpinner.Enable = 'off';
            app.ParameterLabel_2 = uilabel(app.TransformasiLogPanel);
            app.ParameterLabel_2.Text = 'Parameter';
            app.ProsesTransformasiLogButton = uibutton(app.TransformasiLogPanel, 'push');
            app.ProsesTransformasiLogButton.Enable = 'off';
            app.ProsesTransformasiLogButton.Text = 'Proses Transformasi Log';
            app.TransformasiPangkatPanel = uipanel(app.PerbaikanKualitasTab);
            app.TransformasiPangkatPanel.Title = 'Transformasi Pangkat';
            app.cSpinner_2Label = uilabel(app.TransformasiPangkatPanel);
            app.cSpinner_2Label.Enable = 'off';
            app.cSpinner_2Label.Text = 'c';
            app.cSpinner_2 = uispinner(app.TransformasiPangkatPanel);
            app.cSpinner_2.Enable = 'off';
            app.Label = uilabel(app.TransformasiPangkatPanel);
            app.Label.Enable = 'off';
            app.Label.Text = 'y';
            app.ySpinner = uispinner(app.TransformasiPangkatPanel);
            app.ySpinner.Enable = 'off';
            app.ParameterLabel_3 = uilabel(app.TransformasiPangkatPanel);
            app.ParameterLabel_3.Text = 'Parameter';
            app.ProsesTransformasiPangkatButton = uibutton(app.TransformasiPangkatPanel, 'push');
            app.ProsesTransformasiPangkatButton.Enable = 'off';
            app.ProsesTransformasiPangkatButton.Text = 'Proses Transformasi Pangkat';
            app.PereganganKontrasPanel = uipanel(app.PerbaikanKualitasTab);
            app.PereganganKontrasPanel.Title = 'Peregangan Kontras';
            app.ProsesPereganganKontrasButton = uibutton(app.PereganganKontrasPanel, 'push');
            app.ProsesPereganganKontrasButton.Enable = 'off';
            app.ProsesPereganganKontrasButton.Text = 'Proses Peregangan Kontras';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end